classdef TrafficSystem_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BmGtLmp                       matlab.ui.control.Lamp
        BoomGatePositionSwitch        matlab.ui.control.ToggleSwitch
        BoomGatePositionLabel         matlab.ui.control.Label
        Lamp2                         matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        TrainPassedSensorSwitch       matlab.ui.control.ToggleSwitch
        TrainPassedSensorSwitchLabel  matlab.ui.control.Label
        TrainApproachingSensorSwitch  matlab.ui.control.ToggleSwitch
        SwitchLabel                   matlab.ui.control.Label
    end

    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

   properties (Access = private)
        LeftLightBinding
        RightLightBinding
        WarningSoundBinding
    end  
    
    methods (Access = private)
        
        function TrainWarningLights(app)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: TrainApproachingSensorSwitch
        function TrainApproachingSensorSwitchValueChanged(app, event)
            if strcmp(app.TrainApproachingSensorSwitch.Value, "On")
                trVal = '1';
                app.Lamp.Color = 'green';
            else
                trVal = '0';
                app.Lamp.Color = [0.6 0.6 0.6];
            end
            set_param(['GUI_Model', '/trainSensCon'], 'Value', trVal);
        end

        % Value changed function: TrainPassedSensorSwitch
        function TrainPassedSensorSwitchValueChanged(app, event)
            if strcmp(app.TrainPassedSensorSwitch.Value, "On")
                trVal = '1';
                app.Lamp2.Color = 'green';
            else
                trVal = '0';
                app.Lamp2.Color = [0.6 0.6 0.6];
            end
            set_param(['GUI_Model', '/trainPassedCon'], 'Value', trVal);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = {'Train Approaching '; 'Sensor'};
            app.TrainApproachingSensorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.TrainApproachingSensorSwitch.ValueChangedFcn = createCallbackFcn(app, @TrainApproachingSensorSwitchValueChanged, true);
            app.TrainPassedSensorSwitchLabel = uilabel(app.UIFigure);
            app.TrainPassedSensorSwitchLabel.Text = {'Train Passed '; 'Sensor'};
            app.TrainPassedSensorSwitch = uiswitch(app.UIFigure, 'toggle');
            app.TrainPassedSensorSwitch.ValueChangedFcn = createCallbackFcn(app, @TrainPassedSensorSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.BoomGatePositionLabel = uilabel(app.UIFigure);
            app.BoomGatePositionLabel.Text = {'Boom Gate '; 'Position'};
            app.BoomGatePositionSwitch = uiswitch(app.UIFigure, 'toggle');
            app.BoomGatePositionSwitch.Items = {'Down', 'Up'};
            app.BoomGatePositionSwitch.Value = 'Down';
            app.BmGtLmp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrafficSystem_UI

            % Associate the Simulink Model
            app.Simulation = simulation('GUI_Model');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end